classdef Pullback < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FilterButton                   matlab.ui.control.Button
        RowNumberEditField             matlab.ui.control.NumericEditField
        RowNumberEditFieldLabel        matlab.ui.control.Label
        PullbackDistancefromCentrepointLabel  matlab.ui.control.Label
        EMErrorWithoutRotationsButton  matlab.ui.control.Button
        EMErrorWithRotationsButton     matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EMErrorWithRotationsButton
        function EMErrorWithRotationsButtonPushed(app, event)
            t = readtable("Pullback_EMerrorWR (1).xlsx","Sheet",1);
            app.UITable.Data = t;
        end

        % Button pushed function: EMErrorWithoutRotationsButton
        function EMErrorWithoutRotationsButtonPushed(app, event)
            t = readtable("Pullback_EMerror.xlsx","Sheet",1);
            app.UITable.Data = t;
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            filterPullbackrow = app.RowNumberEditField.Value;
            
            % numRows = size(app.t,1);
            % 
            % k = 1;
            % 
            % for i = 1:numRows
            %     if app.UITable.Data{i,2} <= filterPullbackrow
            %         RowstoShow(1,k) = i;
            %         k = k+1;
            %     end
            % end
            % 
            % app.UITable.Data(RowstoShow,:) = [];

            numRows = size(app.t, 1);
    % Initialize RowstoShow as an empty cell array
    RowstoShow = cell(0, size(app.UITable.Data, 2));
    for i = 1:numRows
        if i == filterRowNumber
            % Store the specific row data in RowstoShow
            RowstoShow = [RowstoShow; app.UITable.Data(i, :)];
        end
    end
    % Update the UITable with the filtered data
    app.UITable.Data = RowstoShow;
        end
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X Co-odrinate'; 'Y Co-odrinate'; 'Z Co-odrinate'};
            app.UITable.RowName = {};
            app.EMErrorWithRotationsButton = uibutton(app.UIFigure, 'push');
            app.EMErrorWithRotationsButton.ButtonPushedFcn = createCallbackFcn(app, @EMErrorWithRotationsButtonPushed, true);
            app.EMErrorWithRotationsButton.Text = 'EM Error With Rotations';
            app.EMErrorWithoutRotationsButton = uibutton(app.UIFigure, 'push');
            app.EMErrorWithoutRotationsButton.ButtonPushedFcn = createCallbackFcn(app, @EMErrorWithoutRotationsButtonPushed, true);
            app.EMErrorWithoutRotationsButton.Text = 'EM Error Without Rotations';
            app.PullbackDistancefromCentrepointLabel = uilabel(app.UIFigure);
            app.PullbackDistancefromCentrepointLabel.Text = 'Pullback Distance from Centrepoint';
            app.RowNumberEditFieldLabel = uilabel(app.UIFigure);
            app.RowNumberEditFieldLabel.Text = 'Row Number';
            app.RowNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilterButton = uibutton(app.UIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pullback

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end